classdef UploadApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        UploadTab                       matlab.ui.container.Tab
        MessageTextArea                 matlab.ui.control.TextArea
        MessageTextAreaLabel            matlab.ui.control.Label
        UploadfilestodatabaseButton     matlab.ui.control.Button
        MetadatainputTab                matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        OptionalmetadatainputPanel      matlab.ui.container.Panel
        MandatorymetadatainputPanel     matlab.ui.container.Panel
        DetectionclassifierButtonGroup  matlab.ui.container.ButtonGroup
        OtherPleasespecifyButton        matlab.ui.control.RadioButton
        HEL1Button                      matlab.ui.control.RadioButton
        KERNOButton                     matlab.ui.control.RadioButton
        DetectioninformationLabel       matlab.ui.control.Label
        DeploymentinformationLabel      matlab.ui.control.Label
        ActivationdatetimeEditField     matlab.ui.control.EditField
        ActivationdatetimeEditFieldLabel  matlab.ui.control.Label
        DatavaliduntildatetimeEditField  matlab.ui.control.EditField
        DatavaliduntildatetimeEditFieldLabel  matlab.ui.control.Label
        RecoverydatetimeEditField       matlab.ui.control.EditField
        RecoverydatetimeEditFieldLabel  matlab.ui.control.Label
        DeploymentdatetimeEditField     matlab.ui.control.EditField
        DeploymentdatetimeEditFieldLabel  matlab.ui.control.Label
        ReceivernameEditField           matlab.ui.control.EditField
        ReceivernameEditFieldLabel      matlab.ui.control.Label
        LongitudeEditField              matlab.ui.control.NumericEditField
        LongitudeEditFieldLabel         matlab.ui.control.Label
        LatitudeEditField               matlab.ui.control.NumericEditField
        LatitudeEditFieldLabel          matlab.ui.control.Label
        StationnameEditField            matlab.ui.control.EditField
        StationnameEditFieldLabel       matlab.ui.control.Label
        ProjectnameEditField            matlab.ui.control.EditField
        ProjectnameEditFieldLabel       matlab.ui.control.Label
        MooringtypeButtonGroup          matlab.ui.container.ButtonGroup
        bottommooringButton             matlab.ui.control.RadioButton
        SurfacebuoyButton               matlab.ui.control.RadioButton
        FileselectionTab                matlab.ui.container.Tab
        FilesSelectedTextArea           matlab.ui.control.TextArea
        FilesSelectedTextAreaLabel      matlab.ui.control.Label
        SelectfilesButton               matlab.ui.control.Button
    end

    % Properties for storing data
    properties (Access = private)
        SelectedFilePath % Path of the selected file
        conn             % Database connection
        files
    end
    
    
    methods (Access = private)
        
        function updateTextArea(app, files)
            app.FilesSelectedTextArea.Value = files ;
            numLines = numel(app.FilesSelectedTextArea.Value);
            app.FilesSelectedTextArea.Position(4) = 22 * numLines; % adjust height based on number of lines
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: not associated with a component
        function SelectfilesButtonPushed(app, event)
            [app.files, path] = uigetfile('*.*', 'MultiSelect','on') ; % function to select multiple files
            if isequal(app.files, 0)
                disp('User selected Cancel');
            else
                if ischar(app.files)
                    app.files = {app.files}; % ensure files is in a cell array
                end
                app.files ;
                app.SelectedFilePath = fullfile(path, app.files);
                app.updateTextArea(app.files); % Display file names in the app window
                % OBS: move the enable of Upload button until after the
                % mandatory metadata has been filled out!!!
                app.UploadfilestodatabaseButton.Enable = true; % Enable the Upload button
                app.MessageTextArea.Value = 'Files selected. Ready to upload.';
            end
        end

        % Callback function: not associated with a component
        function UploadfilesButtonPushed(app, event)
            if isempty(app.SelectedFilePath)
                uialert(app.UIFigure, 'No file selected', 'Error');
                return;
            end
            
            % Turn both select and upload button off to indicate that the
            % app is working:
            app.SelectfilesButton.Enable = 'off';
            app.UploadfilestodatabaseButton.Enable = 'off';
            app.MessageTextArea.Value = 'Starting the upload process...';

            % Establish a connection to the SQL Server
            % Define the DSN-less connection string
            connStr = 'Driver={SQL Server};Server=D54296\SQLEXPRESS;Database=mydatabase;Trusted_Connection=yes;';

            % Establish the database connection using the DSN-less connection string
            app.conn = odbc(connStr);

            if isopen(app.conn)

                % create a waitbar to show the progress
                h = waitbar(0, 'Reading and uploading files...') ;
                totalFiles = numel(app.SelectedFilePath);
                for i = 1:totalFiles
                    
                    % Update message for reading file
                    app.MessageTextArea.Value = sprintf('Reading file %d of %d...', i, totalFiles);

                    % Read files
                    fileID = i;
                    %fileData = uint8(fileread(app.SelectedFilePath{i}));
                    podfile = app.SelectedFilePath{i};
                    [poddata] = importpoddata(podfile, 'detstart', 0, 'maxdet', 10); % OBS: this is set to only read the first N data points to speed up tests and troubleshooting
                    
                    % Update message for import
                    app.MessageTextArea.Value = sprintf(['Imported ' num2str(length(poddata)) ' clicks']);

                    % Upload files
                    fileName = app.files{i};
                    fileName = strrep(fileName, ' ', ''); % remove spaces from file name
                    fileName = fileName(1:min(50, length(fileName)));

                    % Convert binary data to hexadecimal string for SQL insertion
                    %hexFileData = dec2hex(fileData);
                    % hexFileData = reshape(hexFileData', 1, []); % Convert to a single row
                    fileData=getByteStreamFromArray(poddata); % convert to byte array
                    hexFileData = reshape(dec2hex(fileData)', 1, []);% Convert to a single row

                    % Create the SQL query
                    sqlquery = sprintf('INSERT INTO Test (FileID, FileName, FileData) VALUES (%d, ''%s'', 0x%s)', fileID, fileName, hexFileData);

                    % Execute the SQL query with error handling
                    try
                        exec(app.conn, sqlquery);
                        
                    catch ME
                        fprintf('Error uploading', i, ME.message);
                    end

                    % Update waitbar
                    waitbar(i / totalFiles, h, sprintf('Processing file %d of %d...', i, totalFiles))
                end
                close(app.conn);
                % Close waitbar 
                close(h)
                uialert(app.UIFigure, 'Files uploaded successfully', 'Success');
            else
                uialert(app.UIFigure, ['Connection failed: ', app.conn.Message], 'Error');
            end

            % If upload is successful, disable the upload and enable the select button
            app.MessageTextArea.Value = 'Upload process completed.';
            app.SelectfilesButton.Enable = 'on';
            app.UploadfilestodatabaseButton.Enable = 'off';

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FileselectionTab = uitab(app.TabGroup);
            app.FileselectionTab.Title = 'File selection';
            app.SelectfilesButton = uibutton(app.FileselectionTab, 'push');
            app.SelectfilesButton.Text = 'Select files';
            app.FilesSelectedTextAreaLabel = uilabel(app.FileselectionTab);
            app.FilesSelectedTextAreaLabel.Text = 'Files Selected';
            app.FilesSelectedTextArea = uitextarea(app.FileselectionTab);
            app.MetadatainputTab = uitab(app.TabGroup);
            app.MetadatainputTab.Title = 'Metadata input';
            app.GridLayout = uigridlayout(app.MetadatainputTab);
            app.MandatorymetadatainputPanel = uipanel(app.GridLayout);
            app.MandatorymetadatainputPanel.Title = 'Mandatory metadata input';
            app.MooringtypeButtonGroup = uibuttongroup(app.MandatorymetadatainputPanel);
            app.MooringtypeButtonGroup.Title = 'Mooring type';
            app.SurfacebuoyButton = uiradiobutton(app.MooringtypeButtonGroup);
            app.SurfacebuoyButton.Text = 'Surface-buoy';
            app.SurfacebuoyButton.Value = true;
            app.bottommooringButton = uiradiobutton(app.MooringtypeButtonGroup);
            app.bottommooringButton.Text = 'bottom-mooring';
            app.ProjectnameEditFieldLabel = uilabel(app.MandatorymetadatainputPanel);
            app.ProjectnameEditFieldLabel.Text = 'Project name';
            app.ProjectnameEditField = uieditfield(app.MandatorymetadatainputPanel, 'text');
            app.StationnameEditFieldLabel = uilabel(app.MandatorymetadatainputPanel);
            app.StationnameEditFieldLabel.Text = 'Station name';
            app.StationnameEditField = uieditfield(app.MandatorymetadatainputPanel, 'text');
            app.LatitudeEditFieldLabel = uilabel(app.MandatorymetadatainputPanel);
            app.LatitudeEditFieldLabel.Text = 'Latitude';
            app.LatitudeEditField = uieditfield(app.MandatorymetadatainputPanel, 'numeric');
            app.LongitudeEditFieldLabel = uilabel(app.MandatorymetadatainputPanel);
            app.LongitudeEditFieldLabel.Text = 'Longitude';
            app.LongitudeEditField = uieditfield(app.MandatorymetadatainputPanel, 'numeric');
            app.ReceivernameEditFieldLabel = uilabel(app.MandatorymetadatainputPanel);
            app.ReceivernameEditFieldLabel.Text = 'Receiver name';
            app.ReceivernameEditField = uieditfield(app.MandatorymetadatainputPanel, 'text');
            app.DeploymentdatetimeEditFieldLabel = uilabel(app.MandatorymetadatainputPanel);
            app.DeploymentdatetimeEditFieldLabel.Text = 'Deployment datetime';
            app.DeploymentdatetimeEditField = uieditfield(app.MandatorymetadatainputPanel, 'text');
            app.RecoverydatetimeEditFieldLabel = uilabel(app.MandatorymetadatainputPanel);
            app.RecoverydatetimeEditFieldLabel.Text = 'Recovery date time';
            app.RecoverydatetimeEditField = uieditfield(app.MandatorymetadatainputPanel, 'text');
            app.DatavaliduntildatetimeEditFieldLabel = uilabel(app.MandatorymetadatainputPanel);
            app.DatavaliduntildatetimeEditFieldLabel.Text = 'Data valid until datetime';
            app.DatavaliduntildatetimeEditField = uieditfield(app.MandatorymetadatainputPanel, 'text');
            app.ActivationdatetimeEditFieldLabel = uilabel(app.MandatorymetadatainputPanel);
            app.ActivationdatetimeEditFieldLabel.Text = 'Activation date time';
            app.ActivationdatetimeEditField = uieditfield(app.MandatorymetadatainputPanel, 'text');
            app.DeploymentinformationLabel = uilabel(app.MandatorymetadatainputPanel);
            app.DeploymentinformationLabel.Text = 'Deployment information';
            app.DetectioninformationLabel = uilabel(app.MandatorymetadatainputPanel);
            app.DetectioninformationLabel.Text = 'Detection information';
            app.DetectionclassifierButtonGroup = uibuttongroup(app.MandatorymetadatainputPanel);
            app.DetectionclassifierButtonGroup.Title = 'Detection classifier';
            app.KERNOButton = uiradiobutton(app.DetectionclassifierButtonGroup);
            app.KERNOButton.Text = 'KERNO';
            app.KERNOButton.Value = true;
            app.HEL1Button = uiradiobutton(app.DetectionclassifierButtonGroup);
            app.HEL1Button.Text = 'HEL1';
            app.OtherPleasespecifyButton = uiradiobutton(app.DetectionclassifierButtonGroup);
            app.OtherPleasespecifyButton.Text = 'Other (Please specify)';
            app.OptionalmetadatainputPanel = uipanel(app.GridLayout);
            app.OptionalmetadatainputPanel.Title = 'Optional metadata input';
            app.UploadTab = uitab(app.TabGroup);
            app.UploadTab.Title = 'Upload';
            app.UploadfilestodatabaseButton = uibutton(app.UploadTab, 'push');
            app.UploadfilestodatabaseButton.Enable = 'off';
            app.UploadfilestodatabaseButton.Text = 'Upload files to database';
            app.MessageTextAreaLabel = uilabel(app.UploadTab);
            app.MessageTextAreaLabel.Text = 'Message';
            app.MessageTextArea = uitextarea(app.UploadTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UploadApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end